classdef EE515TermProject2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VelocityEditFieldLabel         matlab.ui.control.Label
        VelocityEditField              matlab.ui.control.NumericEditField
        StartingPointEditFieldLabel    matlab.ui.control.Label
        StartingPointEditField         matlab.ui.control.NumericEditField
        RefractoryEditFieldLabel       matlab.ui.control.Label
        RefractoryEditField            matlab.ui.control.NumericEditField
        StrengthEditFieldLabel         matlab.ui.control.Label
        StrengthEditField              matlab.ui.control.NumericEditField
        WavefrontNumberEditFieldLabel  matlab.ui.control.Label
        WavefrontNumberEditField       matlab.ui.control.NumericEditField
        DelayEditFieldLabel            matlab.ui.control.Label
        DelayEditField                 matlab.ui.control.NumericEditField
        PropagateButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PropagateButtonPushed(app, event)
            %Muhammed Saadeddin Koçak 2232346
%% Parameter Definitions
propagationvelocity=app.VelocityEditField.Value;%m/sec
startingpointofactivation=app.StartingPointEditField.Value;%starting point of activation shifted from the zsource in z direction
timebetweendepolarisationandrepolarisationwavefronts=app.RefractoryEditField.Value;%sec
strength=app.StrengthEditField.Value;%mV
numberofsuccessivewavefronts=app.WavefrontNumberEditField.Value;
timedelay=app.DelayEditField.Value;%sec
sigmai=0.4;
sigmao=1;
t=linspace(0,10,10001);%sec
sourcelength=5;%m
%% Locating Electrodes
E=[2.5 0.3 0.5;0.5 0.3 0.5;4.5 0.3 0.5];%z,r,theta
zsource=0;%m, z coordinate of initial point of source
d=zsource+startingpointofactivation;%location of depolarizing wave
r=zsource+startingpointofactivation;%location of repolarizing wave
%% Calculating Depolarizing Potentials
PD=zeros(length(t),3);
for i=1:length(t)
    for j=1:length(E)
        if d>=zsource+sourcelength
            PD(i,j)=0;
        else
            fun=@(x,y) 1./((sqrt((E(j,1)-d).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
            polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
            q=integral2(polarfun,0,2*pi,0,0.05);
            PD(i,j)=q*sigmai*strength*(E(j,1)-d)/(4*pi*sigmao);
        end
    end
    d=d+propagationvelocity*0.001;
end
PR=zeros(length(t),3);
for i=timebetweendepolarisationandrepolarisationwavefronts*1000:length(t)
    for j=1:length(E)
        if r>=zsource+sourcelength
            PR(i,j)=0;
        else
            fun=@(x,y) 1./((sqrt((E(j,1)-r).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
            polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
            q=integral2(polarfun,0,2*pi,0,0.05);
            PR(i,j)=-q*sigmai*strength*(E(j,1)-r)/(4*pi*sigmao);
        end
    end
    r=r+propagationvelocity*0.001;
end
if numberofsuccessivewavefronts==2
    d=zsource+startingpointofactivation;%location of depolarizing wave
    r=zsource+startingpointofactivation;%location of repolarizing wave
    PD2=zeros(length(t),3);
    for i=timedelay*1000:length(t)
        for j=1:length(E)
            if d>=zsource+sourcelength
                PD2(i,j)=0;
            else
                fun=@(x,y) 1./((sqrt((E(j,1)-d).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
                polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
                q=integral2(polarfun,0,2*pi,0,0.05);
                PD2(i,j)=q*sigmai*strength*(E(j,1)-d)/(4*pi*sigmao);
            end
        end
        d=d+propagationvelocity*0.001;
    end
    PD=PD+PD2;
    PR2=zeros(length(t),3);
    for i=(timebetweendepolarisationandrepolarisationwavefronts+timedelay)*1000:length(t)
        for j=1:length(E)
            if r>=zsource+sourcelength
                PR2(i,j)=0;
            else
                fun=@(x,y) 1./((sqrt((E(j,1)-r).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
                polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
                q=integral2(polarfun,0,2*pi,0,0.05);
                PR2(i,j)=-q*sigmai*strength*(E(j,1)-r)/(4*pi*sigmao);
            end
        end
        r=r+propagationvelocity*0.001;
    end
    PR=PR+PR2;
end
P=PD+PR;
V1=P(:,1)-P(:,2);
V2=P(:,2)-P(:,3);
V3=P(:,3)-P(:,1);
%% Presenting Results

% hold on
% plot(app.UIAxes,V1(:,1))
% plot(app.UIAxes,t,V2(:,1))
% plot(app.UIAxes,t,V3(:,1))
% xlabel(app.UIAxes,'Time(sec)')
% ylabel(app.UIAxes,'Potential Difference(mV)')
% legend(app.UIAxes,'P1-P2','P2-P3','P3-P1')
% hold off
% figure
% hold on
% plot(t,P(:,1))
% plot(t,P(:,2))
% plot(t,P(:,3))
% xlabel('Time(sec)')
% ylabel('Potential(mV)')
% legend('Electrode 1','Electrode 2','Electrode 3')


% hold on
% plot(app.UIAxes2,t,PD(:,1))
% plot(app.UIAxes2,t,PR(:,1))
plot(app.UIAxes2,t,P(:,1));
title(app.UIAxes2,'A');
xlabel(app.UIAxes2,'Time(sec)');
ylabel(app.UIAxes2,'Potential(mV)');
% legend(app.UIAxes2,'Depolarizing Pulse','Repolarizing Pulse','Potential Measured')
% % legend('Electrode 1','Electrode 2','Electrode 3')
% hold off
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            value = app.DelayEditField.Value;
            
        end

        % Value changed function: WavefrontNumberEditField
        function WavefrontNumberEditFieldValueChanged(app, event)
            value = app.WavefrontNumberEditField.Value;
            
        end

        % Value changed function: StrengthEditField
        function StrengthEditFieldValueChanged(app, event)
            value = app.StrengthEditField.Value;
            
        end

        % Value changed function: RefractoryEditField
        function RefractoryEditFieldValueChanged(app, event)
            value = app.RefractoryEditField.Value;
            
        end

        % Value changed function: StartingPointEditField
        function StartingPointEditFieldValueChanged(app, event)
            value = app.StartingPointEditField.Value;
            
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            value = app.VelocityEditField.Value;
            
        end

        % Button pushed function: PropagateButton
        function PropagateButtonPushed2(app, event)
                        %Muhammed Saadeddin Koçak 2232346
%% Parameter Definitions
propagationvelocity=app.VelocityEditField.Value;%m/sec
startingpointofactivation=app.StartingPointEditField.Value;%starting point of activation shifted from the zsource in z direction
timebetweendepolarisationandrepolarisationwavefronts=app.RefractoryEditField.Value;%sec
strength=app.StrengthEditField.Value;%mV
numberofsuccessivewavefronts=app.WavefrontNumberEditField.Value;
timedelay=app.DelayEditField.Value;%sec
sigmai=0.4;
sigmao=1;
t=linspace(0,10,10001);%sec
sourcelength=5;%m
%% Locating Electrodes
E=[2.5 0.3 0.5;0.5 0.3 0.5;4.5 0.3 0.5];%z,r,theta
zsource=0;%m, z coordinate of initial point of source
d=zsource+startingpointofactivation;%location of depolarizing wave
r=zsource+startingpointofactivation;%location of repolarizing wave
%% Calculating Depolarizing Potentials
PD=zeros(length(t),3);
for i=1:length(t)
    for j=1:length(E)
        if d>=zsource+sourcelength
            PD(i,j)=0;
        else
            fun=@(x,y) 1./((sqrt((E(j,1)-d).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
            polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
            q=integral2(polarfun,0,2*pi,0,0.05);
            PD(i,j)=q*sigmai*strength*(E(j,1)-d)/(4*pi*sigmao);
        end
    end
    d=d+propagationvelocity*0.001;
end
PR=zeros(length(t),3);
for i=timebetweendepolarisationandrepolarisationwavefronts*1000:length(t)
    for j=1:length(E)
        if r>=zsource+sourcelength
            PR(i,j)=0;
        else
            fun=@(x,y) 1./((sqrt((E(j,1)-r).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
            polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
            q=integral2(polarfun,0,2*pi,0,0.05);
            PR(i,j)=-q*sigmai*strength*(E(j,1)-r)/(4*pi*sigmao);
        end
    end
    r=r+propagationvelocity*0.001;
end
if numberofsuccessivewavefronts==2
    d=zsource+startingpointofactivation;%location of depolarizing wave
    r=zsource+startingpointofactivation;%location of repolarizing wave
    PD2=zeros(length(t),3);
    for i=timedelay*1000:length(t)
        for j=1:length(E)
            if d>=zsource+sourcelength
                PD2(i,j)=0;
            else
                fun=@(x,y) 1./((sqrt((E(j,1)-d).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
                polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
                q=integral2(polarfun,0,2*pi,0,0.05);
                PD2(i,j)=q*sigmai*strength*(E(j,1)-d)/(4*pi*sigmao);
            end
        end
        d=d+propagationvelocity*0.001;
    end
    PD=PD+PD2;
    PR2=zeros(length(t),3);
    for i=(timebetweendepolarisationandrepolarisationwavefronts+timedelay)*1000:length(t)
        for j=1:length(E)
            if r>=zsource+sourcelength
                PR2(i,j)=0;
            else
                fun=@(x,y) 1./((sqrt((E(j,1)-r).^2+(E(j,2)-x).^2+(E(j,3)-y).^2)).^3);
                polarfun=@(theta,r) fun(r.*cos(theta),r.*sin(theta));
                q=integral2(polarfun,0,2*pi,0,0.05);
                PR2(i,j)=-q*sigmai*strength*(E(j,1)-r)/(4*pi*sigmao);
            end
        end
        r=r+propagationvelocity*0.001;
    end
    PR=PR+PR2;
end
P=PD+PR;
V1=P(:,1)-P(:,2);
V2=P(:,2)-P(:,3);
V3=P(:,3)-P(:,1);
%% Presenting Results
cla(app.UIAxes);
cla(app.UIAxes2);
% hold(app.UIAxes,'on');
% plot(app.UIAxes,V1(:,1));
% plot(app.UIAxes,t,V2(:,1));
% plot(app.UIAxes,t,V3(:,1));
% xlabel(app.UIAxes,'Time(sec)');
% ylabel(app.UIAxes,'Potential Difference(mV)');
% legend(app.UIAxes,'P1-P2','P2-P3','P3-P1');
% hold(app.UIAxes,'off');
% hold off

% figure
hold(app.UIAxes,'on');
plot(app.UIAxes,t,P(:,1))
plot(app.UIAxes,t,P(:,2))
plot(app.UIAxes,t,P(:,3))
xlabel(app.UIAxes,'Time(sec)')
ylabel(app.UIAxes,'Potential(mV)')
legend(app.UIAxes,'Electrode 1','Electrode 2','Electrode 3')
hold(app.UIAxes,'off');

hold(app.UIAxes2,'on');
plot(app.UIAxes2,t,PD(:,1));
plot(app.UIAxes2,t,PR(:,1));
plot(app.UIAxes2,t,P(:,1));
xlabel(app.UIAxes2,'Time(sec)');
ylabel(app.UIAxes2,'Potential(mV)');
legend(app.UIAxes2,'Depolarizing Pulse','Repolarizing Pulse','Potential Measured');
hold(app.UIAxes2,'off');
% % legend('Electrode 1','Electrode 2','Electrode 3')
% hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.StartingPointEditFieldLabel = uilabel(app.UIFigure);
            app.StartingPointEditFieldLabel.Text = 'Starting Point';
            app.StartingPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartingPointEditFieldValueChanged, true);
            app.RefractoryEditFieldLabel = uilabel(app.UIFigure);
            app.RefractoryEditFieldLabel.Text = 'Refractory';
            app.RefractoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefractoryEditField.ValueChangedFcn = createCallbackFcn(app, @RefractoryEditFieldValueChanged, true);
            app.StrengthEditFieldLabel = uilabel(app.UIFigure);
            app.StrengthEditFieldLabel.Text = 'Strength';
            app.StrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrengthEditField.ValueChangedFcn = createCallbackFcn(app, @StrengthEditFieldValueChanged, true);
            app.WavefrontNumberEditFieldLabel = uilabel(app.UIFigure);
            app.WavefrontNumberEditFieldLabel.Text = 'Wavefront Number';
            app.WavefrontNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavefrontNumberEditField.ValueChangedFcn = createCallbackFcn(app, @WavefrontNumberEditFieldValueChanged, true);
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.PropagateButton = uibutton(app.UIFigure, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed2, true);
            app.PropagateButton.Text = 'Propagate';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE515TermProject2App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end